classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        AbsenceAlarmaftersLabel        matlab.ui.control.Label
        AbsenceAlarmaftersSpinner      matlab.ui.control.Spinner
        DrowsinessSwitchLabel          matlab.ui.control.Label
        DrowsinessSwitch               matlab.ui.control.Switch
        DetectionSwitchLabel           matlab.ui.control.Label
        DetectionSwitch                matlab.ui.control.ToggleSwitch
        WorkoMeterLabel                matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        SinglesittingDurationsSpinnerLabel  matlab.ui.control.Label
        SinglesittingDurationsSpinner  matlab.ui.control.Spinner
        ImagePreviewLabel              matlab.ui.control.Label
        FaceDetectionLabel             matlab.ui.control.Label
        AlarmSwitchLabel               matlab.ui.control.Label
        AlarmSwitch                    matlab.ui.control.Switch
    end

    
    properties (Access = public)
        appOn = true; % Check if app is on. Flipped by the switch
        vid;         % The videoinput  
        Interval = 20; % Time interval between Image captures
        warningDialog = struct('WindowStyle','modal','Interpreter','tex'); % Dialog to show with different messages
        drowsinessDetection = false; % Toggle drowsiness detection
        player;
        alarm = true;
        
        % These are variables to keep track of Time
        Time_present_limit;% Alert if User is present for this much time to take a break
        Time_absent_limit; % Sound alarm if absent for this much time
        Curr_time_absent_limit = 10; % Ignore absence for this much time

        Timer_Present = 0;
        Timer_absent = 0;
        curr_absent = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AbsenceAlarmaftersSpinner
        function AbsenceAlarmaftersSpinnerValueChanged(app, event)
            value = app.AbsenceAlarmaftersSpinner.Value;
            app.Time_absent_limit = value;
        end

        % Value changed function: DetectionSwitch
        function DetectionSwitchValueChanged(app, event)
            value = app.DetectionSwitch.Value;
            switch value
                case 'On'
                    app.appOn = true;
                    
                    % Set up camera and do a video preview inside the app
                    app.vid = videoinput('winvideo', 1);
                    hImage = image(app.UIAxes,zeros(720,1280,3));
                    app.UIAxes.XLim = [0,1280];
                    app.UIAxes.YLim = [0,720];
                    app.UIAxes.XTick = [];
                    app.UIAxes.YTick = [];
                    pbaspect(app.UIAxes,[1280,720,1]);
                    preview(app.vid,hImage);
                    
                    % Create Audio Player
                    [y, Fs] = audioread('alarm.mp3');
                    app.player = audioplayer(y, Fs);
                    
                    % Initialise variables
                    app.Time_present_limit = app.SinglesittingDurationsSpinner.Value;
                    app.Time_absent_limit = app.AbsenceAlarmaftersSpinner.Value;
                    
                    
                    % Set UIAxes2
                    app.UIAxes2.XTick = [];
                    app.UIAxes2.YTick = [];
                    
                    % Do in infinite loop
                    while app.appOn
                        if ~isvalid(app.vid)
                            break;
                        end
                        [inputImage, fname] = capture(app.vid);
                        [OutputImage, BBOX] = detectFace(inputImage, fname);
                        imshow(OutputImage, 'parent', app.UIAxes2);
                        updateVariables(app, BBOX);
                        pause(app.Interval);
                    end
                case 'Off'
                    app.appOn = false;
                    closepreview(app.vid);
                    drawnow;
                    delete(app.vid);
                    warndlg('The Camera is off now','Off Warning', app.warningDialog);
            end
        end

        % Value changed function: SinglesittingDurationsSpinner
        function SinglesittingDurationsSpinnerValueChanged(app, event)
            app.Time_present_limit = app.SinglesittingDurationsSpinner.Value;
            % Changed the single sitting duration
        end

        % Callback function
        function AlarmSwitchValueChanged(app, event)
            
        end

        % Value changed function: AlarmSwitch
        function AlarmSwitchValueChanged2(app, event)
            value = app.AlarmSwitch.Value;
            switch value
                case 'On'
                    app.alarm = true;
                case 'Off'
                    app.alarm = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AbsenceAlarmaftersLabel = uilabel(app.UIFigure);
            app.AbsenceAlarmaftersLabel.Text = 'Absence Alarm after: (s)  ';
            app.AbsenceAlarmaftersSpinner = uispinner(app.UIFigure);
            app.AbsenceAlarmaftersSpinner.Step = 5;
            app.AbsenceAlarmaftersSpinner.Limits = [1 3600];
            app.AbsenceAlarmaftersSpinner.ValueChangedFcn = createCallbackFcn(app, @AbsenceAlarmaftersSpinnerValueChanged, true);
            app.AbsenceAlarmaftersSpinner.Value = 35;
            app.DrowsinessSwitchLabel = uilabel(app.UIFigure);
            app.DrowsinessSwitchLabel.Text = 'Drowsiness';
            app.DrowsinessSwitch = uiswitch(app.UIFigure, 'slider');
            app.DrowsinessSwitch.Value = 'On';
            app.DetectionSwitchLabel = uilabel(app.UIFigure);
            app.DetectionSwitchLabel.Text = 'Detection';
            app.DetectionSwitch = uiswitch(app.UIFigure, 'toggle');
            app.DetectionSwitch.ValueChangedFcn = createCallbackFcn(app, @DetectionSwitchValueChanged, true);
            app.WorkoMeterLabel = uilabel(app.UIFigure);
            app.WorkoMeterLabel.Text = 'Work-o-Meter';
            app.Label = uilabel(app.UIFigure);
            app.Label.Tag = 'timerText';
            app.Label.Text = '00:00';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.SinglesittingDurationsSpinnerLabel = uilabel(app.UIFigure);
            app.SinglesittingDurationsSpinnerLabel.Text = 'Single sitting Duration: (s)';
            app.SinglesittingDurationsSpinner = uispinner(app.UIFigure);
            app.SinglesittingDurationsSpinner.Step = 10;
            app.SinglesittingDurationsSpinner.Limits = [1 7200];
            app.SinglesittingDurationsSpinner.ValueChangedFcn = createCallbackFcn(app, @SinglesittingDurationsSpinnerValueChanged, true);
            app.SinglesittingDurationsSpinner.Value = 50;
            app.ImagePreviewLabel = uilabel(app.UIFigure);
            app.ImagePreviewLabel.Text = 'Image Preview';
            app.FaceDetectionLabel = uilabel(app.UIFigure);
            app.FaceDetectionLabel.Text = 'Face Detection';
            app.AlarmSwitchLabel = uilabel(app.UIFigure);
            app.AlarmSwitchLabel.Text = 'Alarm Switch';
            app.AlarmSwitch = uiswitch(app.UIFigure, 'slider');
            app.AlarmSwitch.ValueChangedFcn = createCallbackFcn(app, @AlarmSwitchValueChanged2, true);
            app.AlarmSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end